classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        Label        matlab.ui.control.Label
        EditField    matlab.ui.control.EditField
        Button       matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Label_2      matlab.ui.control.Label
        Label_3      matlab.ui.control.Label
        Label_4      matlab.ui.control.Label
        EditField_2  matlab.ui.control.EditField
        Label_5      matlab.ui.control.Label
        EditField_3  matlab.ui.control.EditField
        Button_3     matlab.ui.control.Button
        Button_4     matlab.ui.control.Button
        Label_6      matlab.ui.control.Label
        Button_5     matlab.ui.control.Button
        Label_9      matlab.ui.control.Label
        DropDown     matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        m = []; % map
        food = 0;
        water = 0;
        money = 0;
        weathers = [];
        day = 1;
        parameters = table();
        node = 1;
        validDests = [];
    end
    
    methods (Access = private)
        
        function str = getWeather(app)
            switch (app.weathers(app.day))
                case 1
                    str = '晴天';
                case 2
                    str = '高温';
                case 3
                    str = '沙尘暴';
                otherwise
                    str = '不知道';
            end
        end
        
        % Available parameters:
        % food, water, money, water_wgt, food_wgt, encumbrance, income,
        % water_con, food_con, water_price, food_price
        function d = fetch(app, key)
            d = app.parameters(1, key).(1);
            switch (key)
                % Whatever works
                case 'water_con'
                    a = strsplit(d{1}, ',');
                    b = zeros(1, length(a));
                    for i = 1 : length(a)
                        b(i) = str2num(string(a(i)));
                    end
                    d = b;
                case 'food_con'
                    a = strsplit(d{1}, ',');
                    b = zeros(1, length(a));
                    for i = 1 : length(a)
                        b(i) = str2num(string(a(i)));
                    end
                    d = b;
            end
        end
        
        function render(app)
            g = graph(app.m, 'upper', 'omitselfloops');
            title(app.UIAxes, '穿越沙漠');
            h = plot(app.UIAxes, g);
            highlight(h, [app.node], 'NodeColor', 'g');
            
            totalWgt = app.food * app.fetch('food_wgt') + app.water * app.fetch('water_wgt');
            app.Label_2.Text = strcat('你有 ', num2str(app.food), ' 食物, ', num2str(app.water), ' 水, ', num2str(app.money), ' 块钱, 重量 ', num2str(totalWgt) , '/', num2str(app.fetch('encumbrance')));
            app.Label_3.Text = strcat('今天是 ', app.getWeather(), ' 今天是第 ', num2str(app.day), ' 天, 你位于节点 ', num2str(app.node));
            
            if app.day > 1
                app.Button_3.Enable = false;            
            end
            
            app.validDests = [];
            for i = 1 : length(app.m)
                if app.m(app.node, i) == 1
                    app.validDests = [app.validDests i];
                end
            end
            app.DropDown.Items = string(app.validDests);
            app.DropDown.ItemsData = app.validDests;
            
            if app.weathers(app.day) == 3
                app.Button_5.Enable = false;
            else
                app.Button_5.Enable = true;
            end
        end
        
        function ret = purchase(app, foodCount, waterCount)
            if app.day > 1
                disp('不是第 1 天');
                ret = false;
                return;
            end
            sum = waterCount * app.fetch('water_price') + foodCount * app.fetch('food_price');
            if sum >= app.money
                disp('钱不够');
                ret = false;
                return;
            end
            if (app.food + foodCount) * app.fetch('food_wgt') + (app.water + waterCount) * app.fetch('water_wgt') > app.fetch('encumbrance') 
                disp('装不下');
                ret = false;
                return;
            end
            app.money = app.money - sum;
            app.food = app.food + foodCount;
            app.water = app.water + waterCount;
            ret = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            T = readtable('data/challenge.1.csv');
            arr = table2array(T);
            for i = 1 : length(arr)
                app.m(arr(i, 1), arr(i, 2)) = 1;
                app.m(arr(i, 2), arr(i, 1)) = 1;
            end
            app.parameters = readtable('data/parameters.csv');
            app.weathers = table2array(readtable('data/weathers.csv'));
            app.food = app.parameters(1, 'food').(1);
            app.water = app.parameters(1, 'water').(1);
            app.money = app.parameters(1, 'money').(1);
            app.render();
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            input = app.EditField.Value;
            strsplit(input, ",");
            input = str2num(input);
            
            app.m(input(1, 1), input(1, 2)) = 1;
            app.m(input(1, 2), input(1, 1)) = 1;
            app.render();
            disp(app.m);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.m = [];
            app.render();
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            waterCount = str2num(app.EditField_2.Value);
            foodCount = str2num(app.EditField_3.Value);
            app.purchase(foodCount, waterCount);
            app.render();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '加邻接点';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '加';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '清除所有';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '这里会出现废话';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '天气之类的玩意儿';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '买水';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '买吃的';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '买';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '逗留';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '你要干啥？';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '出发';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '前往';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {};
            app.DropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end